classdef a03_video_exp_presentation_stimuli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        expUIFigure                    matlab.ui.Figure
        instructionButton              matlab.ui.control.Button
        consentCheckBox                matlab.ui.control.CheckBox
        imageDisplay                   matlab.ui.control.Image
        Nothungryatall0Veryhungry100Label  matlab.ui.control.Label
        hungrySlider                   matlab.ui.control.Slider
        Notthirstyatall0Verythirsty100Label  matlab.ui.control.Label
        thirstySlider                  matlab.ui.control.Slider
        Notfullatall0Veryfull100Label  matlab.ui.control.Label
        fullSlider                     matlab.ui.control.Slider
        Veryunpleasant0Verypleasant100Label  matlab.ui.control.Label
        pleasantSlider                 matlab.ui.control.Slider
        Notatall0Extremely100Label     matlab.ui.control.Label
        arousalSlider                  matlab.ui.control.Slider
        Veryunhealthy0Veryhealthy100Label  matlab.ui.control.Label
        healthySlider                  matlab.ui.control.Slider
        Notatall0Extremely100Label_2   matlab.ui.control.Label
        likingSlider                   matlab.ui.control.Slider
        hungryLabel                    matlab.ui.control.Label
        thirstyLabel                   matlab.ui.control.Label
        fullLabel                      matlab.ui.control.Label
        pleasantLabel                  matlab.ui.control.Label
        arousingLabel                  matlab.ui.control.Label
        healthyLabel                   matlab.ui.control.Label
        likeLabel                      matlab.ui.control.Label
        titleLabel                     matlab.ui.control.Label
        explanationLabel               matlab.ui.control.Label
        haveEatenRadio                 matlab.ui.container.ButtonGroup
        YesRadio                       matlab.ui.control.RadioButton
        NotSeenRadio                   matlab.ui.control.RadioButton
        SeenNotEatenRadio              matlab.ui.control.RadioButton
        seriousnessLabel               matlab.ui.control.Label
        Notatall0Veryserious100Label   matlab.ui.control.Label
        seriousnessSlider              matlab.ui.control.Slider
        timerLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        instruction_counter = '01Hi'; % Counter for the instruction images
        stimuli_counter = 1; % Counter for stimulis
        computer_screen = get(0,'screensize'); 
        
        insDirectory = '.\matlab_stimuli_procedure_images\'; %instruction slides directory
        stimuliDirectory = '.\CROCUFID_stimuli_items_plated\'; %for stimuli images list and the images
        outDirectory = '.\Data_Collected\'; %collected subject's data will be in this directory
        
        subData = ''; %moving subject info and data back and forth between different functions
        subDataHeader = ''; %moving subject data table header back and forth between different functions
        dataOutFile = ''; %This file can be read through experiment and save the collected data
        
        trial_img_stimuli_list = ''; %preprocessed stimuli list for 50 subject's data collection 
        current_stimuli_id = 1;
        current_stimuli_session = 1;
        
        %keeping stimuli for a specific amount of time
        plus_sign_pause_time = 1; 
        stimulus_pause_time = 1;
        
        %to keep the track of the time during stimuli
        stimulusTimeStart = '';
        stimulusTimeEnd = '';
        restTimeStart = '';
        restTimeEnd = '';
        responseTimeStart = '';
        responseTimeEnd = '';
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %app.expUIFigure.Position = computer_screen; %extending the presntation windwo
            
            %%%%%Setting up initial parameters
            app.expUIFigure.WindowState = 'maximized';
            app.consentCheckBox.Visible = 'off';
            
            app.hungrySlider.Visible = 'off';
            app.Nothungryatall0Veryhungry100Label.Visible = 'off';
            app.hungryLabel.Visible = 'off';
            
            app.thirstySlider.Visible = 'off';
            app.Notthirstyatall0Verythirsty100Label.Visible = 'off';
            app.thirstyLabel.Visible = 'off';
            
            app.fullSlider.Visible = 'off';
            app.Notfullatall0Veryfull100Label.Visible = 'off';
            app.fullLabel.Visible = 'off';
            
            app.pleasantSlider.Visible = 'off';
            app.Veryunpleasant0Verypleasant100Label.Visible = 'off';
            app.pleasantLabel.Visible = 'off';
            
            app.arousalSlider.Visible = 'off';
            app.Notatall0Extremely100Label.Visible = 'off';
            app.arousingLabel.Visible = 'off';
            
            app.healthySlider.Visible = 'off';
            app.Veryunhealthy0Veryhealthy100Label.Visible = 'off';
            app.healthyLabel.Visible = 'off';
            
            app.likingSlider.Visible = 'off';
            app.Notatall0Extremely100Label_2.Visible = 'off';
            app.likeLabel.Visible = 'off';
            
            app.titleLabel.Position = [0 app.computer_screen(4)-100 app.computer_screen(3) 30];
            app.titleLabel.Visible = 'off';
            app.explanationLabel.Visible = 'off';
            
            app.haveEatenRadio.Position = [50 app.computer_screen(4)-120 app.computer_screen(3) 120];
            app.haveEatenRadio.Visible = 'off';
            
            app.seriousnessLabel.Visible = 'off';
            app.Notatall0Veryserious100Label.Visible = 'off';
            app.seriousnessSlider.Visible = 'off';
            
            app.timerLabel.Position = [0 0 70 70];
            app.timerLabel.Text = '';
            app.timerLabel.BackgroundColor = [1 1 1];
            
            
            a03f_show01Hi(app);  %calling the first frame to start the presentation
            
        end

        % Button pushed function: instructionButton
        function instructionButtonPushed(app, event)
            
            switch app.instruction_counter
                case '01Hi'
                    a03f_show02AboutEXP(app);
                case '02AboutEXP'
                    a03f_show03Consent(app);
                case '03Consent'
                    a03f_show0301aboutYou(app);
                case '0301aboutYou'
                    a03f_show0302prepareInputFile(app);
                    a03f_show04Instruction1(app);
                case '04Instruction1'
                    a03f_show05Instruction2(app);
                case '05Instruction2'
                    a03f_show06Practice(app);
                case '06Practice'
                    a03f_show0601Test1(app);
                case '0601Test1'
                    a03f_show0602Test2(app);
                case '0602Test2'
                    a03f_show07getStarted(app);
                case '07getStarted'
                    app.instructionButton.Visible = 'off'; %immediate removal of the button to avoid double clicks
                    if ((rem(app.current_stimuli_id-1, 15) == 0) && (app.current_stimuli_id-1~=75)) && (app.current_stimuli_id~=1)
                        app.responseTimeEnd = datestr(now,'mmmm dd, yyyy HH:MM:SS.FFF'); %calculating response time
                        app.timerLabel.BackgroundColor = [1 1 1]; %keeping track for camera synchronization
                        a03f_show0803saveStimuli(app);
                    end
                    a03f_show08refreshSession(app);
                case '08refreshSession'
                    app.instructionButton.Visible = 'off'; %immediate removal of the button to avoid double clicks
                    a03f_show0801expStimuli(app);
                    a03f_show0802continueStimuli(app);
                case '0802continueStimuli'
                    app.instructionButton.Visible = 'off';  %immediate removal of the button to avoid double clicks
                    app.responseTimeEnd = datestr(now,'mmmm dd, yyyy HH:MM:SS.FFF'); %calculating response time
                    app.timerLabel.BackgroundColor = [1 1 1]; %keeping track for camera synchronization
                    a03f_show0803saveStimuli(app);
                    a03f_show0802continueStimuli(app);
                case '09Seriousness'
                    app.responseTimeEnd = datestr(now,'mmmm dd, yyyy HH:MM:SS.FFF'); %calculating response time
                    app.timerLabel.BackgroundColor = [1 1 1]; %keeping track for camera synchronization
                    a03f_show0803saveStimuli(app);
                    a03f_show09seriousnessCheck(app);
                case '10finalThanks'
                    a03f_show10finalThanks(app);
                otherwise
                    %Goodbye;
            end
            
            
        end

        % Value changed function: consentCheckBox
        function consentCheckBoxValueChanged(app, event)
            if app.consentCheckBox.Value==1
                app.instructionButton.Visible = 'on';
            else
                app.instructionButton.Visible = 'off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.expUIFigure = uifigure('Visible', 'off');
            app.expUIFigure.Name = 'Food Stimulation Experiment';
            app.instructionButton = uibutton(app.expUIFigure, 'push');
            app.instructionButton.ButtonPushedFcn = createCallbackFcn(app, @instructionButtonPushed, true);
            app.instructionButton.Text = 'instructionButton';
            app.consentCheckBox = uicheckbox(app.expUIFigure);
            app.consentCheckBox.ValueChangedFcn = createCallbackFcn(app, @consentCheckBoxValueChanged, true);
            app.consentCheckBox.Text = 'Yes! I agree';
            app.imageDisplay = uiimage(app.expUIFigure);
            app.Nothungryatall0Veryhungry100Label = uilabel(app.expUIFigure);
            app.Nothungryatall0Veryhungry100Label.Text = {'Not hungry at all (0)'; 'Very hungry (100)'};
            app.hungrySlider = uislider(app.expUIFigure);
            app.hungrySlider.MinorTicks = [];
            app.Notthirstyatall0Verythirsty100Label = uilabel(app.expUIFigure);
            app.Notthirstyatall0Verythirsty100Label.Text = {'Not thirsty at all (0)'; 'Very thirsty (100)'};
            app.thirstySlider = uislider(app.expUIFigure);
            app.thirstySlider.MinorTicks = [];
            app.Notfullatall0Veryfull100Label = uilabel(app.expUIFigure);
            app.Notfullatall0Veryfull100Label.Text = {'Not full at all (0)'; 'Very full (100)'};
            app.fullSlider = uislider(app.expUIFigure);
            app.fullSlider.MinorTicks = [];
            app.Veryunpleasant0Verypleasant100Label = uilabel(app.expUIFigure);
            app.Veryunpleasant0Verypleasant100Label.Text = {'Very unpleasant (0)'; 'Very pleasant(100)'};
            app.pleasantSlider = uislider(app.expUIFigure);
            app.pleasantSlider.MinorTicks = [];
            app.Notatall0Extremely100Label = uilabel(app.expUIFigure);
            app.Notatall0Extremely100Label.Text = {'Not at all (0)'; 'Extremely(100)'};
            app.arousalSlider = uislider(app.expUIFigure);
            app.arousalSlider.MinorTicks = [];
            app.Veryunhealthy0Veryhealthy100Label = uilabel(app.expUIFigure);
            app.Veryunhealthy0Veryhealthy100Label.Text = {'Very unhealthy (0)'; 'Very healthy(100)'};
            app.healthySlider = uislider(app.expUIFigure);
            app.healthySlider.MinorTicks = [];
            app.Notatall0Extremely100Label_2 = uilabel(app.expUIFigure);
            app.Notatall0Extremely100Label_2.Text = {'Not at all (0)'; 'Extremely(100)'};
            app.likingSlider = uislider(app.expUIFigure);
            app.likingSlider.MinorTicks = [];
            app.hungryLabel = uilabel(app.expUIFigure);
            app.hungryLabel.Text = '1. How hungry are you right now?';
            app.thirstyLabel = uilabel(app.expUIFigure);
            app.thirstyLabel.Text = '2. How thirsty are you right now?';
            app.fullLabel = uilabel(app.expUIFigure);
            app.fullLabel.Text = '3. How full do you feel right now?';
            app.pleasantLabel = uilabel(app.expUIFigure);
            app.pleasantLabel.Text = '2. How pleasant is this item?';
            app.arousingLabel = uilabel(app.expUIFigure);
            app.arousingLabel.Text = '3. How arousing is this item?';
            app.healthyLabel = uilabel(app.expUIFigure);
            app.healthyLabel.Text = '4. How healthy do you think this item is?';
            app.likeLabel = uilabel(app.expUIFigure);
            app.likeLabel.Text = {'5. How much would you like to eat this '; 'item right now if it is in front of you?'};
            app.titleLabel = uilabel(app.expUIFigure);
            app.titleLabel.Text = 'Title Label';
            app.explanationLabel = uilabel(app.expUIFigure);
            app.explanationLabel.Text = 'Explanation Label';
            app.haveEatenRadio = uibuttongroup(app.expUIFigure);
            app.haveEatenRadio.Title = '1. Have you ever eaten the product in this image?';
            app.YesRadio = uiradiobutton(app.haveEatenRadio);
            app.YesRadio.Text = 'Yes';
            app.YesRadio.Value = true;
            app.NotSeenRadio = uiradiobutton(app.haveEatenRadio);
            app.NotSeenRadio.Text = 'No, I haven''t seen it before';
            app.SeenNotEatenRadio = uiradiobutton(app.haveEatenRadio);
            app.SeenNotEatenRadio.Text = 'No, but I have seen it before';
            app.seriousnessLabel = uilabel(app.expUIFigure);
            app.seriousnessLabel.Text = 'Please rate your overall seriousness throughout the experiment and then click ''next''';
            app.Notatall0Veryserious100Label = uilabel(app.expUIFigure);
            app.Notatall0Veryserious100Label.Text = {'Not at all (0)'; 'Very serious(100)'};
            app.seriousnessSlider = uislider(app.expUIFigure);
            app.seriousnessSlider.MinorTicks = [];
            app.timerLabel = uilabel(app.expUIFigure);
            app.timerLabel.Text = 'timer label';
            % Show the figure after all components are created
            app.expUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = a03_video_exp_presentation_stimuli

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.expUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.expUIFigure)
        end
    end
end